classdef Tb3Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        Slam_Mode_switch        matlab.ui.control.Switch
        SlamModeLabel           matlab.ui.control.Label
        Go_To_Mode_Layout       matlab.ui.container.GridLayout
        GridLayout4             matlab.ui.container.GridLayout
        GridLayout5_2           matlab.ui.container.GridLayout
        Go_button               matlab.ui.control.Button
        GridLayout5             matlab.ui.container.GridLayout
        Load_Map_button         matlab.ui.control.Button
        X_Spinner               matlab.ui.control.Spinner
        XSpinnerLabel           matlab.ui.control.Label
        Y_Spinner               matlab.ui.control.Spinner
        YSpinnerLabel           matlab.ui.control.Label
        DestinationPointLabel   matlab.ui.control.Label
        Go_To_Map               matlab.ui.control.UIAxes
        Slam_Mode_Layout        matlab.ui.container.GridLayout
        GridLayout3             matlab.ui.container.GridLayout
        Save_button             matlab.ui.control.Button
        Reset_button            matlab.ui.control.Button
        Teleop_control_2        matlab.ui.container.GridLayout
        StopButton_2            matlab.ui.control.Button
        BackButton_2            matlab.ui.control.StateButton
        LeftButton_2            matlab.ui.control.StateButton
        RightButton_2           matlab.ui.control.StateButton
        ForwardButton_2         matlab.ui.control.StateButton
        Slam_plot               matlab.ui.control.UIAxes
        Slam_Mode_On_lamp       matlab.ui.control.Lamp
        Slam_Mode_Off_lamp      matlab.ui.control.Lamp
        Object_Mode_switch      matlab.ui.control.Switch
        ObjectModeLabel         matlab.ui.control.Label
        Object_Mode_On_lamp     matlab.ui.control.Lamp
        Object_Mode_Off_lamp    matlab.ui.control.Lamp
        Go_To_Mode_Off_lamp     matlab.ui.control.Lamp
        Go_To_Mode_On_lamp      matlab.ui.control.Lamp
        Go_To_Mode_switch       matlab.ui.control.Switch
        Go_To_Mode_label        matlab.ui.control.Label
        Manual_Mode_Off_lamp    matlab.ui.control.Lamp
        Manual_Mode_On_lamp     matlab.ui.control.Lamp
        Manual_Mode_switch      matlab.ui.control.Switch
        ManualModeSwitchLabel   matlab.ui.control.Label
        IP_Address_textedit     matlab.ui.control.EditField
        ROSIP_label             matlab.ui.control.Label
        Connect_btn             matlab.ui.control.Button
        Connect_lamp            matlab.ui.control.Lamp
        Object_Mode_Layout      matlab.ui.container.GridLayout
        GridLayout2             matlab.ui.container.GridLayout
        Object_Size_spinner     matlab.ui.control.Spinner
        ObjectSizeSpinnerLabel  matlab.ui.control.Label
        Tolerance_spinner       matlab.ui.control.Spinner
        ToleranceSpinnerLabel   matlab.ui.control.Label
        Angular_Speed_label     matlab.ui.control.Label
        Angular_Speed_slider    matlab.ui.control.Slider
        Lineal_Speed_label      matlab.ui.control.Label
        Lineal_Speed_slider     matlab.ui.control.Slider
        Robot_Camera_Obj        matlab.ui.control.UIAxes
        Manual_Mode_Layout      matlab.ui.container.GridLayout
        Teleop_control          matlab.ui.container.GridLayout
        StopButton              matlab.ui.control.Button
        BackButton              matlab.ui.control.StateButton
        LeftButton              matlab.ui.control.StateButton
        RightButton             matlab.ui.control.StateButton
        ForwardButton           matlab.ui.control.StateButton
        Robot_Camera            matlab.ui.control.UIAxes
        Lidar_plot              matlab.ui.control.UIAxes
    end

            
    properties (Access = public)
        Ip_Address = "" % Turtlebot IP address
        Connected = false % Boolean that determines if the user is connected to the robot
        handles % Ros topics
        % Robot Velocity struct
        RobotVel = struct('forV',0,'turnV',0);

        Object_Size = 70;
        Tolerance_Obj = 5;

        Lidar_Map % Lidar progress map
        Slam_Map % Output map of Slam

        Timer timer

        Going_To = false;
    end
    
    methods (Access = public)

        % Velocity control publisher
        function Tb3SetVel(app)

            persistent velMsg
            
            if isempty(velMsg)
                velMsg = rosmessage(app.handles.velPub);
            end
            
            velMsg.Linear.X = app.RobotVel.forV;
            velMsg.Angular.Z = app.RobotVel.turnV;
            send(app.handles.velPub,velMsg);
        end


        % View receiver
        function Tb3View(app)
                
           %while(app.Manual_Mode_switch.Value == "On" && ~(app.Slam_Mode_switch.Value == "On"))

               lidar = lidarScan(receive(app.handles.laserSub));
    
               plot(lidar,'Parent',app.Lidar_plot)

                imgMsg = receive(app.handles.imgSub);
                rgbImg = readImage(imgMsg);


                imsow = imshow(rgbImg,'Parent',app.Robot_Camera);
                % Remove tick labels
                app.Robot_Camera.XAxis.TickLabels = {};
                app.Robot_Camera.YAxis.TickLabels = {};
                % Set limits of axes
                app.Robot_Camera.XLim = [0 imsow.XData(2)];
                app.Robot_Camera.YLim = [0 imsow.YData(2)];

           %end
        end

        function Tb3View_Obj(app)
                
           %while(app.Object_Mode_switch.Value == "On")
                imgMsg = receive(app.handles.imgSub);
                rgbImg = readImage(imgMsg);
                imsow = imshow(rgbImg,'Parent',app.Robot_Camera_Obj);
                % Remove tick labels
                app.Robot_Camera.XAxis.TickLabels = {};
                app.Robot_Camera.YAxis.TickLabels = {};
                % Set limits of axes
                app.Robot_Camera.XLim = [0 imsow.XData(2)];
                app.Robot_Camera.YLim = [0 imsow.YData(2)];

                %if(app.Object_Mode_switch.Value ~= "On" || app.Manual_Mode_switch.Value == "On" || app.Go_To_Mode_switch.Value == "On")
                 %   break
                %end
           %end
        end
        
        % Object Follower
        function ObjectFollower(app)
            % Load control parameters
            params = controlParams(app);

            %% SENSE
            % Grab images
            img = readImage(app.handles.imgSub.LatestMessage);
            
            %% PROCESS
            % Object detection algorithm
            resizeScale = 0.5;
            [centerX,centerY,circleSize] = detectCircle(app,img,resizeScale);
            % Object tracking algorithm
            [v,w] = trackCircle(app,centerX,circleSize,size(img,2),params);
            
            %% CONTROL
            % Package ROS message and send to the robot
            app.RobotVel.forV = v; % Add sliders
            app.RobotVel.turnV = w;
            Tb3SetVel(app)
            
            %% VISUALIZE
            % Annotate image and update the video player
            img = insertShape(img,'Circle',[centerX centerY circleSize/2],'LineWidth',2);
            imshow(img,"Parent",app.Robot_Camera_Obj);
            
        end
        
        function Tb3Slam(app)

           % while(app.Slam_Mode_switch.Value == "On")
                %[app.Slam_Map, app.Lidar_Map] = lidarSlam_funct(app.handles.laserSub,app.Slam_plot, app.Lidar_Map);
                %show(app.Lidar_Map,"Parent",app.Slam_plot)
                 maxLidarRange = 8;
                 mapResolution = 20;
                 [isScanAccepted, ~, ~] = addScan(app.Lidar_Map, lidarScan(receive(app.handles.laserSub)));
                 if isScanAccepted
                     %fprintf('Added scan\n');
                     [scans, optimizedPoses]  = scansAndPoses(app.Lidar_Map);
                     app.Slam_Map = buildMap(scans, optimizedPoses, mapResolution, maxLidarRange);
                     show(app.Lidar_Map,"Parent",app.Slam_plot);
                 end
            %end
            
        end

        function Tb3Move_To(app)
            
            show(app.Slam_Map,"Parent",app.Go_To_Map)
            odomdata = receive(app.handles.odomSub,3);
            pose = odomdata.Pose.Pose;

            x = pose.Position.X;
            y = pose.Position.Y;
            hold(app.Go_To_Map, "on")
            plot(x,y,"Marker","*","Parent",app.Go_To_Map)
            hold(app.Go_To_Map, "off")

            
            if app.Going_To
                quat = pose.Orientation;
                angles = quat2eul([quat.W quat.X quat.Y quat.Z]);
                theta = angles(1);
                dist_x = app.X_Spinner.Value - x;
                dist_y = app.Y_Spinner.Value - y;
                % angle between the current orientation of the robot (about z-axis) and
                % the goal position from the present position
                angle_to_goal = atan2(dist_y, dist_x);
    
                % the robot will initially estimate the angle and turn towards it at a
                % given angular velocity and then starts moving towards it at a given   
                % linear velocity until it reaches the end position
                if (abs(angle_to_goal - theta) < 0.1)
                    app.RobotVel.forV = 0.6;
                    app.RobotVel.turnV = 0.0;
                elseif (abs(x-app.X_Spinner.Value)<0.2 && abs(y-app.Y_Spinner.Value)<0.2)
                    app.RobotVel.forV = 0.0;
                    app.RobotVel.turnV = 0.0;
                    app.Going_To = false;
                else
                    app.RobotVel.forV = 0.0;
                    app.RobotVel.turnV = 0.25;
                end
                
                % send daata to the robot in Gazebo
                Tb3SetVel(app)
            end
            
        end

        function mytimercallback(app, ~, ~)
            if(app.Manual_Mode_switch.Value == "On")   
                Tb3View(app)
            end

            if(app.Object_Mode_switch.Value == "On")
                ObjectFollower(app)
            end

            if(app.Slam_Mode_switch.Value == "On")
                Tb3Slam(app)
            end

            if(app.Go_To_Mode_switch.Value == "On")
                Tb3Move_To(app)
            end


        end
    end

    methods (Access = private)
        
        function params = controlParams(app)
            params.Ts = 0.1;          % Sample time

            params.bufSize = 5;        % Filter buffer size
            params.maxDisp = 300;      % Max object displacement [pixels]
            params.minSize = 50;       % Min object size [pixels]
            params.maxSize = 500;      % Max object size [pixels]
            params.maxCounts = 5;      % Max outlier counts before stopping
        
            params.linVelGain = 2e-3;  % Linear control gain
            params.angVelGain = 5e-4;  % Angular control gain
            params.maxLinVel = app.Lineal_Speed_slider.Value;%0.2;   % Max linear speed
            params.maxAngVel = app.Angular_Speed_slider.Value;%0.75;   % Max angular speed
        
            params.posDeadZone = 30;   % Steering control marker position dead zone [pixels] 
            params.targetSize = app.Object_Size;%180;   % Linear speed control target blob size [pixels]
            params.sizeDeadZone = app.Tolerance_Obj;%30;  % Linear speed control size dead zone [pixels]
            params.speedRedSize = 100; % Minimum pixel value before turning speed is ramped down
            
        end
        
        function [v,w] = trackCircle(app,x,blobSize,imgWidth,params)
            %% Initialize persistent variables
            persistent xBuffer xPrev sizeBuffer outlierCount
            if isempty(xBuffer); xBuffer = zeros(1,params.bufSize); end
            if isempty(sizeBuffer); sizeBuffer = zeros(1,params.bufSize); end
            if isempty(xPrev); xPrev = x; end
            if isempty(outlierCount); outlierCount = 0; end
             
            %% Input processing
            % Count outliers
            if (abs(x-xPrev)<params.maxDisp) && (blobSize>params.minSize) && (blobSize<params.maxSize)      
                outlierCount = 0;
            else
                outlierCount = min(outlierCount+1,1000); % Increment with saturation
            end
            % Update and average the measurement buffers
            xBuffer = [xBuffer(2:params.bufSize) x];
            sizeBuffer = [sizeBuffer(2:params.bufSize) blobSize];
            xFilt = mean(xBuffer);
            sizeFilt = mean(sizeBuffer);
            xPrev = x;
            
            %% Angular velocity control: Keep the marker centered
            w = 0;
            if outlierCount < params.maxCounts        
                posError = xFilt - imgWidth/2;
                if abs(posError) > params.posDeadZone
                    speedReduction = max(sizeFilt/params.speedRedSize,1);
                    w = -params.angVelGain*posError*speedReduction;
                end
                if w > params.maxAngVel
                    w = params.maxAngVel;
                elseif w < -params.maxAngVel
                    w = -params.maxAngVel;
                end
            end
        
            %% Linear velocity control: Keep the marker at a certain distance
            v = 0;
            if outlierCount < params.maxCounts      
                sizeError = params.targetSize - sizeFilt;
                if abs(sizeError) > params.sizeDeadZone
                    v = params.linVelGain*sizeError;
                end
                if v > params.maxLinVel
                    v = params.maxLinVel;
                elseif v < -params.maxLinVel
                    v = -params.maxLinVel;
                end
            end
        
            %% Scan autonomously for a while if the outlier count has been exceeded
            if outlierCount > 50 && outlierCount < 500
               w = params.maxAngVel/2; 
            end
            
        end
        
        function [centerX,centerY,blobSize] = detectCircle(app,img,scale)
             % Initialize variables
            centerX = 0;
            centerY = 0;
            blobSize = 0;
            
            % Resize the image
            img = imresize(img,scale,'Antialiasing',false);
            
            % Create mask based on chosen histogram thresholds
            % For this example, we convert to L*a*b* space and then threshold
            % Thresholds for blue marker
            LMin = 0;
            LMax = 70;
            aMin = -100;
            aMax = 100;
            bMin = -100;
            bMax = -15;
            labImg = rgb2lab(img);
            imgBW = (labImg(:,:,1)>=LMin)&(labImg(:,:,1)<=LMax)& ...
                    (labImg(:,:,2)>=aMin)&(labImg(:,:,2)<=aMax)& ...
                    (labImg(:,:,3)>=bMin)&(labImg(:,:,3)<=bMax);
                 
            % Detect blobs
            persistent detector
            if isempty(detector)
                detector = vision.BlobAnalysis( ...
                            'BoundingBoxOutputPort',false, ...
                            'AreaOutputPort',false, ...
                            'MajorAxisLengthOutputPort', true, ...
                            'MinimumBlobArea',300, ...
                            'MaximumCount', 10);
            end
            [centroids,majorAxes] = detector(imgBW);
            
            % Estimate the blob location and size, if any are large enough
            if ~isempty(majorAxes)
                
                % Find max blob major axis
                [blobSize,maxIdx] = max(majorAxes);
                blobSize = double(blobSize(1));
                
                % Find location of largest blob
                maxLoc = centroids(maxIdx,:);
                centerX = double(maxLoc(1));
                centerY = double(maxLoc(2));
                
            end
            
            % Rescale outputs
            centerX = centerX/scale;
            centerY = centerY/scale;
            blobSize = blobSize/scale;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % It is necessary to store the ip at startup if the user does
            % not change anything
            app.Ip_Address = app.IP_Address_textedit.Value;

            % Inizialization
            app.Lidar_Map = lidarSLAM(20, 8);
            app.Lidar_Map.LoopClosureThreshold = 210;  
            app.Lidar_Map.LoopClosureSearchRadius = 8;
            app.Timer = timer('Period',0.5,'ExecutionMode','fixedSpacing',...
                           'TasksToExecute', Inf);
            app.Timer.TimerFcn = @app.mytimercallback; % Your Timer Callback

        end

        % Value changed function: IP_Address_textedit
        function IP_Address_texteditValueChanged(app, event)
            % Ip get
            app.Ip_Address = string(app.IP_Address_textedit.Value); % Get
            
        end

        % Button pushed function: Connect_btn
        function Connect_btnPushed(app, event)
            
            % We shutdown all the opened sessions of ros
            rosshutdown

            if(~app.Connected)
                connection_dir = "http://"+ app.Ip_Address + ":11311";
    
                % Connects to the ros which is running on the virtual machine
                rosinit(connection_dir)
                % If the connection is done, we connecto to the gazebo's Turtlebot 3
                turtleBotVersion = 3; % Gazebo Office world uses TurtleBot3 Burger model
    
                % We set the connected boolean to true
                app.Connected = true;
    
                % We change the color of the connected lamp and the button text
                app.Connect_lamp.Color = [0.00,1.00,0.00];
                app.Connect_btn.Text = "Connected";

                % Allowing interaction with the switchs
                app.Manual_Mode_switch.Enable = true;
                app.Manual_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Go_To_Mode_switch.Enable = true;
                app.Go_To_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Object_Mode_switch.Enable = true;
                app.Object_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Slam_Mode_switch.Enable = true;
                app.Slam_Mode_Off_lamp.Color = [1.00,0.00,0.00];

                % We subscribe to the topics and stuff
                % If the connection is done, we connecto to the gazebo's Turtlebot 3
                turtleBotVersion = 3; % Gazebo Office world uses TurtleBot3 Burger model
                % We subscribe to the odometry topic
                app.handles.odomSub = rossubscriber("/odom","BufferSize",25,"DataFormat","struct");
                % We establish the frecuency of the receiving data
                receive(app.handles.odomSub,3);
                % We subscribe to the laser topic
                app.handles.laserSub = rossubscriber("/scan");%,"BufferSize",5,"DataFormat","struct");
                receive(app.handles.laserSub,3);

                app.handles.imgSub = rossubscriber("/camera/rgb/image_raw");
                receive(app.handles.imgSub,3);

                % For connecting to the turtlebot 3 we publish through the /cmd_vel
                % topic, for connecting to other turtlebots we publish in the
                % correspondent topic
                if turtleBotVersion == 3
                    velTopic = "/cmd_vel";
                else
                    velTopic = "/mobile_base/commands/velocity";
                end
                
                % We establish the velocity publisher
                app.handles.velPub = rospublisher(velTopic,"DataFormat","struct");
                Tb3Slam(app);
                start(app.Timer);
                
            else
                stop(app.Timer);
                % We set the connected boolean to true
                app.Connected = false;
    
                % We change the color of the connected lamp and the button text
                app.Connect_lamp.Color = [1.00,0.00,0.00];
                app.Connect_btn.Text = "Disconnected";

                % Avoid interaction with the switchs

                % Manual Mode avoidance
                app.Manual_Mode_switch.Enable = false;
                app.Manual_Mode_Off_lamp.Color = [0.94,0.94,0.94];
                app.Manual_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Manual_Mode_switch.Value = "Off";
                app.Manual_Mode_Layout.Visible = false;

                % Go To Mode avoidance
                app.Go_To_Mode_switch.Enable = false;
                app.Go_To_Mode_Off_lamp.Color = [0.94,0.94,0.94];
                app.Go_To_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Go_To_Mode_switch.Value = "Off";
                app.Go_To_Mode_Layout.Visible = false;

                % Object Mode avoidance
                app.Object_Mode_switch.Enable = false;
                app.Object_Mode_Off_lamp.Color = [0.94,0.94,0.94];
                app.Object_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Object_Mode_switch.Value = "Off";
                app.Object_Mode_Layout.Visible = false;

                % Slam Mode avoidance
                app.Slam_Mode_switch.Enable = false;
                app.Slam_Mode_switch.Value = "Off";
                app.Slam_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Slam_Mode_Off_lamp.Color = [0.94,0.94,0.94];
                app.Slam_Mode_Layout.Visible = false;
            end
            
        end

        % Value changed function: Manual_Mode_switch
        function Manual_Mode_switchValueChanged(app, event)
            value = app.Manual_Mode_switch.Value;
            
            if(value == "On")
                app.Manual_Mode_On_lamp.Color = [0.00,1.00,0.00];
                app.Manual_Mode_Off_lamp.Color = [0.94,0.94,0.94];
                app.Manual_Mode_Layout.Visible = true;
                app.Go_To_Mode_switch.Value = "Off";
                app.Go_To_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Go_To_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Go_To_Mode_Layout.Visible = false;
                app.Object_Mode_switch.Value = "Off";
                app.Object_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Object_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Object_Mode_Layout.Visible = false;
                app.Slam_Mode_switch.Value = "Off";
                app.Slam_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Slam_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Slam_Mode_Layout.Visible = false;
                %Tb3View(app);
            else
                app.Manual_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Manual_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Manual_Mode_Layout.Visible = false;
            end
        end

        % Value changed function: Go_To_Mode_switch
        function Go_To_Mode_switchValueChanged(app, event)
            value = app.Go_To_Mode_switch.Value;
            
            if(value == "On")
                app.Go_To_Mode_On_lamp.Color = [0.00,1.00,0.00];
                app.Go_To_Mode_Off_lamp.Color = [0.94,0.94,0.94];
                app.Go_To_Mode_Layout.Visible = true;
                app.Manual_Mode_switch.Value = "Off";
                app.Manual_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Manual_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Manual_Mode_Layout.Visible = false;
                app.Object_Mode_switch.Value = "Off";
                app.Object_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Object_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Object_Mode_Layout.Visible = false;
                app.Slam_Mode_switch.Value = "Off";
                app.Slam_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Slam_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Slam_Mode_Layout.Visible = false;
            else
                app.Go_To_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Go_To_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Go_To_Mode_Layout.Visible = false;
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % We reset all the movement
            app.BackButton.Value = 0;
            app.LeftButton.Value = 0;
            app.RightButton.Value = 0;
            app.ForwardButton.Value = 0;

            app.RobotVel.forV = 0;
            app.RobotVel.turnV = 0;
            Tb3SetVel(app);

        end

        % Value changed function: ForwardButton
        function ForwardButtonValueChanged(app, event)
            value = app.ForwardButton.Value;
            
            if(value == 1)
                if(app.BackButton.Value == 1)
                    app.BackButton.Value = 0;
                end
                app.RobotVel.forV = 0.2;
            else
                app.RobotVel.forV = 0;
            end
            Tb3SetVel(app);
        end

        % Value changed function: RightButton
        function RightButtonValueChanged(app, event)
            value = app.RightButton.Value;
            
            if(value == 1)
                if(app.LeftButton.Value == 1)
                    app.LeftButton.Value = 0;
                end
                app.RobotVel.turnV = -1;
            else
                app.RobotVel.turnV = 0;
            end
            Tb3SetVel(app);
        end

        % Value changed function: LeftButton
        function LeftButtonValueChanged(app, event)
            value = app.LeftButton.Value;
            
            if(value == 1)
                if(app.RightButton.Value == 1)
                    app.RightButton.Value = 0;
                end
                app.RobotVel.turnV = 1;
            else
                app.RobotVel.turnV = 0;
            end
            Tb3SetVel(app);
        end

        % Value changed function: BackButton
        function BackButtonValueChanged(app, event)
            value = app.BackButton.Value;

            if(value == 1)
                if(app.ForwardButton.Value == 1)
                    app.ForwardButton.Value = 0;
                end
                app.RobotVel.forV = -0.2;
            else
                app.RobotVel.forV = 0;
            end
            Tb3SetVel(app);
        end

        % Value changed function: Object_Mode_switch
        function Object_Mode_switchValueChanged(app, event)
            value = app.Object_Mode_switch.Value;
            
            if(value == "On")
                if app.RobotVel.forV < 0
                    app.RobotVel.forV = 0;
                end
                app.Lineal_Speed_slider.Value = 0.1;
                app.Angular_Speed_slider.Value = 0.1;
                app.Tolerance_spinner.Value = app.Tolerance_Obj;
                app.Object_Size_spinner.Value = app.Object_Size;

                app.Object_Mode_On_lamp.Color = [0.00,1.00,0.00];
                app.Object_Mode_Off_lamp.Color = [0.94,0.94,0.94];
                app.Object_Mode_Layout.Visible = true;
                app.Go_To_Mode_switch.Value = "Off";
                app.Go_To_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Go_To_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Go_To_Mode_Layout.Visible = false;
                app.Manual_Mode_switch.Value = "Off";
                app.Manual_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Manual_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Manual_Mode_Layout.Visible = false;
                app.Slam_Mode_switch.Value = "Off";
                app.Slam_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Slam_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Slam_Mode_Layout.Visible = false;
                
                %ObjectFollower_Funct(app)
                %ObjectFollower(app);
            else
                app.Lineal_Speed_slider.Value = 0;
                app.Angular_Speed_slider.Value = 0;
                app.Object_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Object_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Object_Mode_Layout.Visible = false;
            end
        end

        % Value changed function: ForwardButton_2
        function ForwardButton_2ValueChanged(app, event)
            value = app.ForwardButton_2.Value;
            
            if(value == 1)
                if(app.BackButton_2.Value == 1)
                    app.BackButton_2.Value = 0;
                end
                app.RobotVel.forV = 0.2;
            else
                app.RobotVel.forV = 0;
            end
            Tb3SetVel(app);
        end

        % Value changed function: RightButton_2
        function RightButton_2ValueChanged(app, event)
            value = app.RightButton_2.Value;
            
            if(value == 1)
                if(app.LeftButton_2.Value == 1)
                    app.LeftButton_2.Value = 0;
                end
                app.RobotVel.turnV = -1;
            else
                app.RobotVel.turnV = 0;
            end
            Tb3SetVel(app);
        end

        % Value changed function: BackButton_2
        function BackButton_2ValueChanged(app, event)
            value = app.BackButton_2.Value;
            if(value == 1)
                if(app.ForwardButton_2.Value == 1)
                    app.ForwardButton_2.Value = 0;
                end
                app.RobotVel.forV = -0.2;
            else
                app.RobotVel.forV = 0;
            end
            Tb3SetVel(app);
        end

        % Value changed function: LeftButton_2
        function LeftButton_2ValueChanged(app, event)
            value = app.LeftButton_2.Value;
            if(value == 1)
                if(app.RightButton_2.Value == 1)
                    app.RightButton_2.Value = 0;
                end
                app.RobotVel.turnV = 1;
            else
                app.RobotVel.turnV = 0;
            end
            Tb3SetVel(app);
        end

        % Button pushed function: StopButton_2
        function StopButton_2Pushed(app, event)
            % We reset all the movement
            app.BackButton_2.Value = 0;
            app.LeftButton_2.Value = 0;
            app.RightButton_2.Value = 0;
            app.ForwardButton_2.Value = 0;

            app.RobotVel.forV = 0;
            app.RobotVel.turnV = 0;
            Tb3SetVel(app);

        end

        % Button pushed function: Reset_button
        function Reset_buttonButtonPushed(app, event)
            % Inizialization
            app.Lidar_Map = 0;
            app.Lidar_Map = lidarSLAM(20, 8);
            app.Lidar_Map.LoopClosureThreshold = 210;  
            app.Lidar_Map.LoopClosureSearchRadius = 8;
        end

        % Button pushed function: Save_button
        function Save_buttonButtonPushed(app, event)
            % Save the map
            var = app.Slam_Map;
            save("GeneratedMap.mat","var");
            show(app.Slam_Map,"Parent",app.Go_To_Map)
            exportgraphics(app.Go_To_Map,'GeneratedMap.png','Resolution',300)
            img = imread('GeneratedMap.png');
            imwrite(img, 'GeneratedMap.pgm', 'pgm', 'Encoding', 'ASCII');
        end

        % Value changed function: Slam_Mode_switch
        function Slam_Mode_switchValueChanged(app, event)
            value = app.Slam_Mode_switch.Value;

            if(value == "On")
                app.Slam_Mode_On_lamp.Color = [0.00,1.00,0.00];
                app.Slam_Mode_Off_lamp.Color = [0.94,0.94,0.94];
                app.Slam_Mode_Layout.Visible = true;
                app.Go_To_Mode_switch.Value = "Off";
                app.Go_To_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Go_To_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Go_To_Mode_Layout.Visible = false;
                app.Manual_Mode_switch.Value = "Off";
                app.Manual_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Manual_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Manual_Mode_Layout.Visible = false;
                app.Object_Mode_switch.Value = "Off";
                app.Object_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Object_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Object_Mode_Layout.Visible = false;
                
                %Tb3Slam(app)
            else
                app.Slam_Mode_On_lamp.Color = [0.94,0.94,0.94];
                app.Slam_Mode_Off_lamp.Color = [1.00,0.00,0.00];
                app.Slam_Mode_Layout.Visible = false;
            end
        end

        % Value changed function: Tolerance_spinner
        function Tolerance_spinnerValueChanged(app, event)
            value = app.Tolerance_spinner.Value;
            app.Tolerance_Obj = value;
        end

        % Value changed function: Object_Size_spinner
        function Object_Size_spinnerValueChanged(app, event)
            value = app.Object_Size_spinner.Value;
            app.Object_Size = value;
        end

        % Button pushed function: Load_Map_button
        function Load_Map_buttonButtonPushed(app, event)
            cla(app.Go_To_Map,'reset')
            try
                app.Slam_Map = load('GeneratedMap.mat');
                app.Slam_Map = app.Slam_Map.var;
            catch
                disp('Not .mat file found')
                try
                   image = imread('GeneratedMap.pgm');
                   imageCropped = image(60:765,120:830);
                   imshow(imageCropped)
                   imageNorm = double(imageCropped)/255;
                   imageOccupancy = 1 - imageNorm;
                   app.Slam_Map = occupancyMap(imageOccupancy,20);
                catch
                    disp('Not .pgm file found')

                    try
                       image = imread('GeneratedMap.png');
                       imageCropped = image(60:765,120:830);
                       imageNorm = double(imageCropped)/255;
                       imageOccupancy = 1 - imageNorm;
                       app.Slam_Map = occupancyMap(imageOccupancy,20);
                    catch
                        disp('Not .png file found')
                    end
                end
            end
            show(app.Slam_Map,"Parent",app.Go_To_Map)
            odomdata = receive(app.handles.odomSub,3);
            pose = odomdata.Pose.Pose;

            x = pose.Position.X;
            y = pose.Position.Y;
            hold(app.Go_To_Map, "on")
            plot(x,y,"Marker","*","Parent",app.Go_To_Map)
            hold(app.Go_To_Map, "off")
        end

        % Button pushed function: Go_button
        function Go_buttonButtonPushed(app, event)
            app.Going_To = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Manual_Mode_Layout = uigridlayout(app.GridLayout);
            app.Manual_Mode_Layout.Visible = 'off';
            app.Lidar_plot = uiaxes(app.Manual_Mode_Layout);
            title(app.Lidar_plot, 'Lidar View')
            xlabel(app.Lidar_plot, 'X')
            ylabel(app.Lidar_plot, 'Y')
            zlabel(app.Lidar_plot, 'Z')
            app.Robot_Camera = uiaxes(app.Manual_Mode_Layout);
            title(app.Robot_Camera, 'Robot Camera')
            app.Robot_Camera.XLim = [0 100];
            app.Teleop_control = uigridlayout(app.Manual_Mode_Layout);
            app.ForwardButton = uibutton(app.Teleop_control, 'state');
            app.ForwardButton.ValueChangedFcn = createCallbackFcn(app, @ForwardButtonValueChanged, true);
            app.ForwardButton.Text = 'Forward';
            app.RightButton = uibutton(app.Teleop_control, 'state');
            app.RightButton.ValueChangedFcn = createCallbackFcn(app, @RightButtonValueChanged, true);
            app.RightButton.Text = 'Right';
            app.LeftButton = uibutton(app.Teleop_control, 'state');
            app.LeftButton.ValueChangedFcn = createCallbackFcn(app, @LeftButtonValueChanged, true);
            app.LeftButton.Text = 'Left';
            app.BackButton = uibutton(app.Teleop_control, 'state');
            app.BackButton.ValueChangedFcn = createCallbackFcn(app, @BackButtonValueChanged, true);
            app.BackButton.Text = 'Back';
            app.StopButton = uibutton(app.Teleop_control, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.Object_Mode_Layout = uigridlayout(app.GridLayout);
            app.Object_Mode_Layout.Visible = 'off';
            app.Robot_Camera_Obj = uiaxes(app.Object_Mode_Layout);
            title(app.Robot_Camera_Obj, 'Robot Camera')
            zlabel(app.Robot_Camera_Obj, 'Z')
            app.GridLayout2 = uigridlayout(app.Object_Mode_Layout);
            app.Lineal_Speed_slider = uislider(app.GridLayout2);
            app.Lineal_Speed_slider.Limits = [0 2];
            app.Lineal_Speed_label = uilabel(app.GridLayout2);
            app.Lineal_Speed_label.Text = 'Lineal Speed';
            app.Angular_Speed_slider = uislider(app.GridLayout2);
            app.Angular_Speed_slider.Limits = [0 2];
            app.Angular_Speed_slider.Value = 1;
            app.Angular_Speed_label = uilabel(app.GridLayout2);
            app.Angular_Speed_label.Text = 'Angular Speed';
            app.ToleranceSpinnerLabel = uilabel(app.GridLayout2);
            app.ToleranceSpinnerLabel.Text = 'Tolerance';
            app.Tolerance_spinner = uispinner(app.GridLayout2);
            app.Tolerance_spinner.ValueChangedFcn = createCallbackFcn(app, @Tolerance_spinnerValueChanged, true);
            app.Tolerance_spinner.Value = 5;
            app.ObjectSizeSpinnerLabel = uilabel(app.GridLayout2);
            app.ObjectSizeSpinnerLabel.Text = 'Object Size';
            app.Object_Size_spinner = uispinner(app.GridLayout2);
            app.Object_Size_spinner.ValueChangedFcn = createCallbackFcn(app, @Object_Size_spinnerValueChanged, true);
            app.Object_Size_spinner.Value = 70;
            app.Connect_lamp = uilamp(app.GridLayout);
            app.Connect_btn = uibutton(app.GridLayout, 'push');
            app.Connect_btn.ButtonPushedFcn = createCallbackFcn(app, @Connect_btnPushed, true);
            app.Connect_btn.Text = 'Disconnected';
            app.ROSIP_label = uilabel(app.GridLayout);
            app.ROSIP_label.Text = 'ROS IP:';
            app.IP_Address_textedit = uieditfield(app.GridLayout, 'text');
            app.IP_Address_textedit.ValueChangedFcn = createCallbackFcn(app, @IP_Address_texteditValueChanged, true);
            app.IP_Address_textedit.Value = '192.168.64.128';
            app.ManualModeSwitchLabel = uilabel(app.GridLayout);
            app.ManualModeSwitchLabel.Text = 'Manual Mode';
            app.Manual_Mode_switch = uiswitch(app.GridLayout, 'slider');
            app.Manual_Mode_switch.ValueChangedFcn = createCallbackFcn(app, @Manual_Mode_switchValueChanged, true);
            app.Manual_Mode_switch.Enable = 'off';
            app.Manual_Mode_On_lamp = uilamp(app.GridLayout);
            app.Manual_Mode_Off_lamp = uilamp(app.GridLayout);
            app.Go_To_Mode_label = uilabel(app.GridLayout);
            app.Go_To_Mode_label.Text = 'Go to Mode';
            app.Go_To_Mode_switch = uiswitch(app.GridLayout, 'slider');
            app.Go_To_Mode_switch.ValueChangedFcn = createCallbackFcn(app, @Go_To_Mode_switchValueChanged, true);
            app.Go_To_Mode_switch.Enable = 'off';
            app.Go_To_Mode_On_lamp = uilamp(app.GridLayout);
            app.Go_To_Mode_Off_lamp = uilamp(app.GridLayout);
            app.Object_Mode_Off_lamp = uilamp(app.GridLayout);
            app.Object_Mode_On_lamp = uilamp(app.GridLayout);
            app.ObjectModeLabel = uilabel(app.GridLayout);
            app.ObjectModeLabel.Text = 'Object Mode';
            app.Object_Mode_switch = uiswitch(app.GridLayout, 'slider');
            app.Object_Mode_switch.ValueChangedFcn = createCallbackFcn(app, @Object_Mode_switchValueChanged, true);
            app.Object_Mode_switch.Enable = 'off';
            app.Slam_Mode_Off_lamp = uilamp(app.GridLayout);
            app.Slam_Mode_On_lamp = uilamp(app.GridLayout);
            app.Slam_Mode_Layout = uigridlayout(app.GridLayout);
            app.Slam_Mode_Layout.Visible = 'off';
            app.Slam_plot = uiaxes(app.Slam_Mode_Layout);
            title(app.Slam_plot, 'Map')
            xlabel(app.Slam_plot, 'X')
            ylabel(app.Slam_plot, 'Y')
            zlabel(app.Slam_plot, 'Z')
            app.Teleop_control_2 = uigridlayout(app.Slam_Mode_Layout);
            app.ForwardButton_2 = uibutton(app.Teleop_control_2, 'state');
            app.ForwardButton_2.ValueChangedFcn = createCallbackFcn(app, @ForwardButton_2ValueChanged, true);
            app.ForwardButton_2.Text = 'Forward';
            app.RightButton_2 = uibutton(app.Teleop_control_2, 'state');
            app.RightButton_2.ValueChangedFcn = createCallbackFcn(app, @RightButton_2ValueChanged, true);
            app.RightButton_2.Text = 'Right';
            app.LeftButton_2 = uibutton(app.Teleop_control_2, 'state');
            app.LeftButton_2.ValueChangedFcn = createCallbackFcn(app, @LeftButton_2ValueChanged, true);
            app.LeftButton_2.Text = 'Left';
            app.BackButton_2 = uibutton(app.Teleop_control_2, 'state');
            app.BackButton_2.ValueChangedFcn = createCallbackFcn(app, @BackButton_2ValueChanged, true);
            app.BackButton_2.Text = 'Back';
            app.StopButton_2 = uibutton(app.Teleop_control_2, 'push');
            app.StopButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopButton_2Pushed, true);
            app.StopButton_2.Text = 'Stop';
            app.GridLayout3 = uigridlayout(app.Slam_Mode_Layout);
            app.Reset_button = uibutton(app.GridLayout3, 'push');
            app.Reset_button.ButtonPushedFcn = createCallbackFcn(app, @Reset_buttonButtonPushed, true);
            app.Reset_button.Text = {'Reset'; ''};
            app.Save_button = uibutton(app.GridLayout3, 'push');
            app.Save_button.ButtonPushedFcn = createCallbackFcn(app, @Save_buttonButtonPushed, true);
            app.Save_button.Text = {'Save'; ''};
            app.Go_To_Mode_Layout = uigridlayout(app.GridLayout);
            app.Go_To_Mode_Layout.Visible = 'off';
            app.Go_To_Map = uiaxes(app.Go_To_Mode_Layout);
            title(app.Go_To_Map, 'Title')
            xlabel(app.Go_To_Map, 'X')
            ylabel(app.Go_To_Map, 'Y')
            zlabel(app.Go_To_Map, 'Z')
            app.GridLayout4 = uigridlayout(app.Go_To_Mode_Layout);
            app.DestinationPointLabel = uilabel(app.GridLayout4);
            app.DestinationPointLabel.Text = 'Destination Point';
            app.YSpinnerLabel = uilabel(app.GridLayout4);
            app.YSpinnerLabel.Text = 'Y';
            app.Y_Spinner = uispinner(app.GridLayout4);
            app.XSpinnerLabel = uilabel(app.GridLayout4);
            app.XSpinnerLabel.Text = 'X';
            app.X_Spinner = uispinner(app.GridLayout4);
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.Load_Map_button = uibutton(app.GridLayout5, 'push');
            app.Load_Map_button.ButtonPushedFcn = createCallbackFcn(app, @Load_Map_buttonButtonPushed, true);
            app.Load_Map_button.Text = {'Load Map'; ''};
            app.GridLayout5_2 = uigridlayout(app.GridLayout4);
            app.Go_button = uibutton(app.GridLayout5_2, 'push');
            app.Go_button.ButtonPushedFcn = createCallbackFcn(app, @Go_buttonButtonPushed, true);
            app.Go_button.Text = {'Go'; ''};
            app.SlamModeLabel = uilabel(app.GridLayout);
            app.SlamModeLabel.Text = 'Slam Mode';
            app.Slam_Mode_switch = uiswitch(app.GridLayout, 'slider');
            app.Slam_Mode_switch.ValueChangedFcn = createCallbackFcn(app, @Slam_Mode_switchValueChanged, true);
            app.Slam_Mode_switch.Enable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tb3Gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end